classdef COVID19app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        COVID19                    matlab.ui.Figure
        GridLayout                 matlab.ui.container.GridLayout
        LeftPanel                  matlab.ui.container.Panel
        GridLayout2                matlab.ui.container.GridLayout
        DataSelectButtonGroup      matlab.ui.container.ButtonGroup
        ConfirmedButton            matlab.ui.control.RadioButton
        DeathsButton               matlab.ui.control.RadioButton
        NormalizeCheckBox          matlab.ui.control.CheckBox
        PlotPopulationCheckBox     matlab.ui.control.CheckBox
        CurveFitCheckBox           matlab.ui.control.CheckBox
        RefreshGraphButton         matlab.ui.control.Button
        RegionsnewlineforeachTextAreaLabel  matlab.ui.control.Label
        RegionsInput               matlab.ui.control.TextArea
        FitStartDatePickerLabel    matlab.ui.control.Label
        FitStartDatePicker         matlab.ui.control.DatePicker
        DaystoPredictSpinnerLabel  matlab.ui.control.Label
        DaystoPredictSpinner       matlab.ui.control.Spinner
        CountryDropDownLabel       matlab.ui.control.Label
        CountryDropDown            matlab.ui.control.DropDown
        DaysOffsetSpinnerLabel     matlab.ui.control.Label
        DaysOffsetSpinner          matlab.ui.control.Spinner
        HelpButton                 matlab.ui.control.Button
        RightPanel                 matlab.ui.container.Panel
        GridLayout3                matlab.ui.container.GridLayout
        UIAxes                     matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    properties (Access = private)
        COVID_data_path = ''
        pop_data_path = '';
        selectedData = 'confirmed';
        plot_total_pop = 0;
        normalize_data = 1;
        curve_fit = 1;
        fit_start_date = datetime('2020-03-10','InputFormat','yyyy-MM-dd')
        regions_to_plot = {};
        days_to_predict = 10;
        T = {};
        P = {};
        timedata = datetime([],[],[]);
        regionID = {};
        casedata = [];
        selpath = '';
        offsets = [];
    end
    
    methods (Access = private)
        
       function results = refresh_data(app)
            currentDir = getcurrentdir(app);
            app.pop_data_path = [currentDir filesep 'popData.csv'];
            app.P = readtable(app.pop_data_path);
            app.COVID_data_path = [currentDir filesep 'COVID-19Data',filesep,'csse_covid_19_data',filesep,'csse_covid_19_time_series',filesep,'time_series_covid19_',app.selectedData,'_global.csv'];
            app.T = readtable(app.COVID_data_path);
            row1 = app.T{1,5:end};
            a = '';
            app.timedata = datetime([],[],[]);
            for ii = 1:length(row1)
                a = [row1{ii},'20'];
                if length(a) == 9
                    a = ['0' a];
                end
                app.timedata = [app.timedata; datetime(a,'InputFormat','MM/dd/yyyy')];
            end
            regions = app.T{2:end,1};
            countries = app.T{2:end,2};
            app.regionID = cell(1,1);
            app.casedata = str2double(app.T{2:end,5:end});
            for ii = 1:size(app.casedata,1)
                if isempty(regions{ii})
                    app.regionID{ii} = countries{ii};
                else
                    app.regionID{ii} = regions{ii};
                end
            end
            app.regionID = [app.regionID 'Australia' 'Canada' 'China' 'World'];
            app.casedata(size(app.casedata,1)+1,:) = sum(app.casedata(9:16,:)); %Australia
            app.casedata(size(app.casedata,1)+1,:) = sum(app.casedata(36:46,:)); %Canada
            app.casedata(size(app.casedata,1)+1,:) = sum(app.casedata(50:82,:)); %China
            app.casedata(size(app.casedata,1)+1,:) = sum(app.casedata); %World
           end
        
        function currentDir = getcurrentdir(app)
            if isdeployed % Stand-alone mode.
                [status, result] = system('path');
                currentDir = char(regexpi(result, 'Path=(.*?);', 'tokens', 'once'));
            else % MATLAB mode.
                currentDir = pwd;
            end
        end
    end    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            refresh_data(app)
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.COVID19.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {480, 480};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {220, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end

        % Selection changed function: DataSelectButtonGroup
        function DataSelectButtonGroupSelectionChanged(app, event)
            if app.ConfirmedButton.Value
                app.selectedData = 'confirmed';
            elseif app.DeathsButton.Value
                app.selectedData = 'deaths';
            end
            refresh_data(app)
        end

        % Value changed function: NormalizeCheckBox
        function NormalizeCheckBoxValueChanged(app, event)
            app.normalize_data = app.NormalizeCheckBox.Value;
        end

        % Value changed function: PlotPopulationCheckBox
        function PlotPopulationCheckBoxValueChanged(app, event)
            app.plot_total_pop = app.PlotPopulationCheckBox.Value;
        end

        % Value changed function: CurveFitCheckBox
        function CurveFitCheckBoxValueChanged(app, event)
            app.curve_fit =  app.CurveFitCheckBox.Value;
        end

        % Value changed function: RegionsInput
        function RegionsInputValueChanged(app, event)
            app.regions_to_plot = app.RegionsInput.Value';
            app.CountryDropDown.Items = app.regions_to_plot;
            app.offsets = zeros(length(app.regions_to_plot),1);
            app.DaysOffsetSpinner.Value = 0;
        end

        % Button pushed function: RefreshGraphButton
        function RefreshGraphButtonPushed(app, event)
            warning('off','MATLAB:Axes:NegativeDataInLogAxis')
            warning('off','MATLAB:Axes:NegativeLimitsInLogAxis')
            app.RefreshGraphButton.BackgroundColor = [0.80,0.80,0.80];
            drawnow
            try
            legend_cell = {};
            col = lines(length(app.regions_to_plot));

            plot_total_pop = app.plot_total_pop;
            normalize_data = app.normalize_data;
            
            fit_start_id = find(app.timedata == app.fit_start_date);
            counter = 1;
            n = length(app.timedata);
            ax = app.UIAxes;
            hold(ax,'on')
            cla(ax)
            for ii = 1:size(app.casedata,1)
                if any(strcmp(app.regions_to_plot,app.regionID{ii}))
                    co = app.offsets(strcmp(app.regions_to_plot,app.regionID{ii}));
                    pop_id = find(strcmp(app.regionID{ii},app.P{:,1}));
                    
                    if ~isempty(pop_id)
                        region_pop = app.P{pop_id,3};
                    else
                        if normalize_data
                            app.RefreshGraphButton.BackgroundColor = [0.64,0.08,0.18];
                            error(['Missing population data for ',app.regionID{ii},...
                            '. Check CSV and try again'])
                        else
                            warning(['Missing population data for ',app.regionID{ii},...
                                '. Check CSV and try again'])
                            app.RefreshGraphButton.BackgroundColor = [0.64,0.08,0.18];
                        end
                    end
                    
                    %Exponential Fit
                    expfit = fitlm(datenum(app.timedata(fit_start_id:n)),...
                        log10(app.casedata(ii,fit_start_id:end)));
%                     disp(['Adjusted R Squared:' num2str(expfit.Rsquared.Adjusted)])
                    
                    %Semilog Plot
                    if normalize_data
                        semilogy(ax,app.timedata+co,app.casedata(ii,:)/region_pop,'o--','color',col(counter,:))
                        app.UIAxes.YScale = 'log';
                        if app.curve_fit
                            %Calculate expfit data
                            b = expfit.Coefficients{1,1};
                            m = expfit.Coefficients{2,1};
                            x = datenum(app.timedata(fit_start_id:n));
                            x = [x; (1:app.days_to_predict)'+x( end)];
                            y = 10.^(m*x + b);
                            %Plot
                            plot(ax,datetime(x,'ConvertFrom','datenum')+co,y/region_pop,'-','color',col(counter,:))
                        end
                    else
                        semilogy(ax,app.timedata+co,app.casedata(ii,:),'o--','color',col(counter,:))
                        app.UIAxes.YScale = 'log';
                        %Calculate expfit data
                        if app.curve_fit
                            b = expfit.Coefficients{1,1};
                            m = expfit.Coefficients{2,1};
                            x = datenum(app.timedata(fit_start_id:n));
                            x = [x; (1:app.days_to_predict)'+x( end)];
                            y = 10.^(m*x + b);
                            %Plot
                            plot(ax,datetime(x,'ConvertFrom','datenum')+co,y,'-','color',col(counter,:))
                        end
                        if plot_total_pop
                            switch app.curve_fit
                                case 1
                                    pl = plot(ax,[app.timedata(1),datetime(x(end),'ConvertFrom','datenum')],[region_pop,region_pop],'-.',...
                            'color',col(counter,:));%,'HandleVisibility','off')
                                case 0
                                    pl = plot(ax,[app.timedata(1),app.timedata(end)],[region_pop,region_pop],'-.',...
                            'color',col(counter,:));%,'HandleVisibility','off')
                            end
                            set(get(get(pl,'Annotation'),'LegendInformation'),'IconDisplayStyle','off');
                        end
                    end
                    counter = counter + 1;
                    kk = size(legend_cell,2);
                    legend_cell{kk+1} = app.regionID{ii};
                    if app.curve_fit 
                    legend_cell{kk+2} = ['Doubling time = ',num2str(round(log10(2)/m,2))...
                        ,' days, $R_{adj}^2=$',num2str(expfit.Rsquared.Adjusted)];
                    end
                end
            end
            % subplot(2,1,1)
            region_list = app.regions_to_plot{1};
            for ii = 2:length(app.regions_to_plot)
                region_list = [region_list,', ',app.regions_to_plot{ii}];
            end
            legend(ax,legend_cell,'location','northwest','interpreter','latex')
            if normalize_data
                title(ax,['Normalized ',app.selectedData,' in ',region_list])
                ylabel(ax,[app.selectedData,' normalized by total population'])
                ylim(ax,[-Inf,1])
            else
                title(ax,[app.selectedData,' in ',region_list])
                ylabel(ax,app.selectedData)
                ylim(ax,[-Inf,Inf])
            end
            xlabel(ax,'Date')
            app.RefreshGraphButton.BackgroundColor = [0.47,0.67,0.19];
            catch
                app.RefreshGraphButton.BackgroundColor = [0.64,0.08,0.18];
            end
            if counter < length(app.regions_to_plot)+1
                app.RefreshGraphButton.BackgroundColor = [0.64,0.08,0.18];
            end
            warning('on','MATLAB:Axes:NegativeDataInLogAxis')
            warning('on','MATLAB:Axes:NegativeLimitsInLogAxis')
        end

        % Value changed function: DaystoPredictSpinner
        function DaystoPredictSpinnerValueChanged(app, event)
            app.days_to_predict = app.DaystoPredictSpinner.Value;
        end

        % Value changed function: FitStartDatePicker
        function FitStartDatePickerValueChanged(app, event)
            app.fit_start_date = app.FitStartDatePicker.Value;
        end

        % Value changed function: CountryDropDown
        function CountryDropDownValueChanged(app, event)
            value = app.CountryDropDown.Value;
            id = find(strcmp(app.regions_to_plot,value));
            app.DaysOffsetSpinner.Value = app.offsets(id);
        end

        % Value changed function: DaysOffsetSpinner
        function DaysOffsetSpinnerValueChanged(app, event)
            value = app.DaysOffsetSpinner.Value;
            region = app.CountryDropDown.Value;
            id = find(strcmp(app.regions_to_plot,region));
            app.offsets(id) = value;
        end

        % Button pushed function: HelpButton
        function HelpButtonPushed(app, event)
            msgbox({'-Region names must be formatted precisely as they are in the Johns Hopkins data','',...
                '-US state data is not available as it is no longer being updated','',...
                '-Make sure to use git pull --recurse-submodules','',...
                '-For more information/questions contact michaelvronsky@berkeley.edu','',...
                'Copyright (C) 2020 Michael Vronsky - All Rights Reserved'},...
                'COVID-19 Plotting Tool Help','help')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.COVID19 = uifigure('Visible', 'off');
            app.COVID19.Name = 'UI Figure';
            app.COVID19.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.COVID19);
            app.LeftPanel = uipanel(app.GridLayout);
            app.GridLayout2 = uigridlayout(app.LeftPanel);
            app.DataSelectButtonGroup = uibuttongroup(app.GridLayout2);
            app.DataSelectButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DataSelectButtonGroupSelectionChanged, true);
            app.DataSelectButtonGroup.Title = 'Data Select';
            app.ConfirmedButton = uiradiobutton(app.DataSelectButtonGroup);
            app.ConfirmedButton.Text = 'Confirmed';
            app.ConfirmedButton.Value = true;
            app.DeathsButton = uiradiobutton(app.DataSelectButtonGroup);
            app.DeathsButton.Text = 'Deaths';
            app.NormalizeCheckBox = uicheckbox(app.GridLayout2);
            app.NormalizeCheckBox.ValueChangedFcn = createCallbackFcn(app, @NormalizeCheckBoxValueChanged, true);
            app.NormalizeCheckBox.Text = 'Normalize';
            app.NormalizeCheckBox.Value = true;
            app.PlotPopulationCheckBox = uicheckbox(app.GridLayout2);
            app.PlotPopulationCheckBox.ValueChangedFcn = createCallbackFcn(app, @PlotPopulationCheckBoxValueChanged, true);
            app.PlotPopulationCheckBox.Text = 'Plot Population';
            app.CurveFitCheckBox = uicheckbox(app.GridLayout2);
            app.CurveFitCheckBox.ValueChangedFcn = createCallbackFcn(app, @CurveFitCheckBoxValueChanged, true);
            app.CurveFitCheckBox.Text = 'Curve Fit';
            app.CurveFitCheckBox.Value = true;
            app.RefreshGraphButton = uibutton(app.GridLayout2, 'push');
            app.RefreshGraphButton.ButtonPushedFcn = createCallbackFcn(app, @RefreshGraphButtonPushed, true);
            app.RefreshGraphButton.Text = {'Refresh'; 'Graph'};
            app.RegionsnewlineforeachTextAreaLabel = uilabel(app.GridLayout2);
            app.RegionsnewlineforeachTextAreaLabel.Text = 'Regions (new line for each)';
            app.RegionsInput = uitextarea(app.GridLayout2);
            app.RegionsInput.ValueChangedFcn = createCallbackFcn(app, @RegionsInputValueChanged, true);
            app.FitStartDatePickerLabel = uilabel(app.GridLayout2);
            app.FitStartDatePickerLabel.Text = 'Fit Start';
            app.FitStartDatePicker = uidatepicker(app.GridLayout2);
            app.FitStartDatePicker.DisplayFormat = 'uuuu-MM-dd';
            app.FitStartDatePicker.ValueChangedFcn = createCallbackFcn(app, @FitStartDatePickerValueChanged, true);
            app.FitStartDatePicker.Value = datetime([2020 3 10]);
            app.DaystoPredictSpinnerLabel = uilabel(app.GridLayout2);
            app.DaystoPredictSpinnerLabel.Text = 'Days to Predict';
            app.DaystoPredictSpinner = uispinner(app.GridLayout2);
            app.DaystoPredictSpinner.Limits = [1 Inf];
            app.DaystoPredictSpinner.ValueChangedFcn = createCallbackFcn(app, @DaystoPredictSpinnerValueChanged, true);
            app.DaystoPredictSpinner.Value = 10;
            app.CountryDropDownLabel = uilabel(app.GridLayout2);
            app.CountryDropDownLabel.Text = 'Country';
            app.CountryDropDown = uidropdown(app.GridLayout2);
            app.CountryDropDown.Items = {};
            app.CountryDropDown.ValueChangedFcn = createCallbackFcn(app, @CountryDropDownValueChanged, true);
            app.CountryDropDown.Value = {};
            app.DaysOffsetSpinnerLabel = uilabel(app.GridLayout2);
            app.DaysOffsetSpinnerLabel.Text = 'Days Offset';
            app.DaysOffsetSpinner = uispinner(app.GridLayout2);
            app.DaysOffsetSpinner.ValueChangedFcn = createCallbackFcn(app, @DaysOffsetSpinnerValueChanged, true);
            app.HelpButton = uibutton(app.GridLayout2, 'push');
            app.HelpButton.ButtonPushedFcn = createCallbackFcn(app, @HelpButtonPushed, true);
            app.HelpButton.Text = '?';
            app.RightPanel = uipanel(app.GridLayout);
            app.GridLayout3 = uigridlayout(app.RightPanel);
            app.UIAxes = uiaxes(app.GridLayout3);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.UIAxes.XLim = [-4.03296041622975 -2.08424331622976];
            app.UIAxes.YLim = [-0.236056441211208 1.71266065878879];
            app.UIAxes.ZLim = [0 1];
            % Show the figure after all components are created
            app.COVID19.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = COVID19app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.COVID19)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.COVID19)
        end
    end
end